classdef ARTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PWREditField                matlab.ui.control.NumericEditField
        PWREditFieldLabel           matlab.ui.control.Label
        AOCdegEditField             matlab.ui.control.NumericEditField
        AOCdegEditFieldLabel        matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        InfoboxTextArea             matlab.ui.control.TextArea
        InfoboxTextAreaLabel        matlab.ui.control.Label
        SelectUpdatepositionButton  matlab.ui.control.Button
        HDGdegEditField             matlab.ui.control.NumericEditField
        HDGdegEditFieldLabel        matlab.ui.control.Label
        zPosmEditField              matlab.ui.control.NumericEditField
        zPosmEditFieldLabel         matlab.ui.control.Label
        yPosmEditField              matlab.ui.control.NumericEditField
        yPosmEditFieldLabel         matlab.ui.control.Label
        xPosmEditField              matlab.ui.control.NumericEditField
        xPosmEditFieldLabel         matlab.ui.control.Label
        StartSimButton              matlab.ui.control.Button
        resultPlot                  matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mat               % PreProcessor Data
        sim               % simulation Data after precalc
        imi               % imission struct
        modeDemo = false  % Simulation switch
        numWorkers = 1    % parallel pool default
        localcluster      % parallel pool object

        cb                % colorbar results
        cb_terrain        % colorbar terrain

        x_pos = 3000;     % init pos x
        y_pos = 3000;     % init pos y
        z_pos = 0;        % init pos z
        x_hdg = 0;        % init orientation vec x
        y_hdg = 0;        % init orientation vec y
        z_hdg = 0;        % init orientation vec z
        HDG = 0;          % init heading
        pwrSetting = 100; % initial power setting for the engines
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setting app postion
            screenSize = get(0, 'ScreenSize');
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);

            xMid = (screenSize(3) - figWidth) / 2;
            yMid = (screenSize(4) - figHeight) / 2;

            app.UIFigure.Position = [xMid yMid figWidth figHeight];

            % Init feedback text box
            app.InfoboxTextArea.Value = {'The Demo is loading',...
                                      '-- Please wait --'};
            drawnow;

            % Adding folders
            addpath('images')
            addpath('geodata')
            addpath('input')
            addpath('SRC')

            % Init workspace
            fprintf('---- Init ARTS DEMO ---- \n')
            app.mat = PreProcessor;
            app.sim = PreCalc(app.mat.sim);

            % Init Terrain
            app_showTerrain(app.UIAxes,app.sim.geo);
            app.cb_terrain                  = colorbar(app.UIAxes);
            app.cb_terrain.Label.String     = 'Elevation_{AMSL} [m]';
            app.cb_terrain.Label.FontSize   = 12;
            app.UIAxes.View                 = [0 90];
            

            % Init Position
            app.xPosmEditField.Value    = app.x_pos;
            app.yPosmEditField.Value    = app.y_pos;
            app.zPosmEditField.Value    = app.z_pos;
            app.HDGdegEditField.Value   = app.HDG;

            % setting up multithreading for calculation 
            try
                app.numWorkers = feature('numcores');
                app.localcluster = parpool('local',app.numWorkers);
                assignin('base','localcluster',app.localcluster);
                app.InfoboxTextArea.Value = {'Parallel Pool is set up.',...
                                      'Number of Cores used: ',...
                                      num2str(app.numWorkers), ...  
                                      'Multithreaded ARTS is enabled'};
                drawnow;
            catch err
                
                app.InfoboxTextArea.Value = ...
                            {'------- no parallelpool available -------'};
                drawnow;
                disp(err.message)
                           
            end
            pause(5)
            % Ready feedback text box
            app.InfoboxTextArea.Value = {'This simulation runs in demo mode. ',...
                'You can select a position in space from where a static ',...
                'noise simulation will be conducted'};
            
        end

        % Button pushed function: SelectUpdatepositionButton
        function SelectUpdatepositionButtonPushed(app, event)
            h = findobj(app.UIAxes, 'Type', 'DataTip');

            if isempty(h)
                uialert(app.UIFigure, 'No DataTip is present!', 'Hinweis');
                return
            end

            cell_Pos    = h.Content;
            app.x_pos   = str2double(extractAfter(cell_Pos{1},'X'));
            app.y_pos   = str2double(extractAfter(cell_Pos{2},'Y'));
            app.z_pos   = str2double(extractAfter(cell_Pos{3},'Z'));

            app.xPosmEditField.Value = app.x_pos;
            app.yPosmEditField.Value = app.y_pos;

            if app.zPosmEditField.Value > app.z_pos
                app.z_pos = app.zPosmEditField.Value + 1.0;
            else
                app.zPosmEditField.Value = app.z_pos;
            end

            app.x_hdg = cosd(-app.HDGdegEditField.Value+90);
            app.y_hdg = sind(-app.HDGdegEditField.Value+90);
            app.z_hdg = 0;

            % -- finding z-lim for plotting -------------------------------
            if (app.zPosmEditField.Value+50 > max(max(app.sim.geo.elevation)))
                z_lim = app.zPosmEditField.Value+50;
            else
                z_lim = max(max(app.sim.geo.elevation));
            end

            % Updating the view with emission spot an ac direction
            quiver3(app.resultPlot, app.xPosmEditField.Value,...
                                    app.yPosmEditField.Value,...
                                    app.zPosmEditField.Value,...
                                    app.x_hdg*500,...
                                    app.y_hdg*500,...
                                    app.z_hdg,...
                                    'Color', 'red', ...
                                    'LineWidth', 5,...
                                    'MaxHeadSize', 5);  
            hold(app.resultPlot, 'on');
            app_showTerrain(app.resultPlot,app.sim.geo);
            app.resultPlot.ZLim = [min(min(app.sim.geo.elevation))-50,...
                                     z_lim];
            colormap(app.resultPlot, 'default');
            delete(app.cb);
            hold(app.resultPlot, 'off');
            app.resultPlot.View = [0 90];

            % Writing selected values to text field
            text = {'Selected simulation settings :',...
                    ['x : ',num2str(app.xPosmEditField.Value)],...
                    ['y : ',num2str(app.yPosmEditField.Value)],...
                    ['z : ',num2str(app.zPosmEditField.Value)],...
                    ['hdg : ',num2str(app.HDGdegEditField.Value)],...
                    ['aoc : ',num2str(app.AOCdegEditField.Value)],...
                    ['pwr : ',num2str(app.PWREditField.Value)]};
            app.InfoboxTextArea.Value = text;
        end

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            app.modeDemo = true;
            app.InfoboxTextArea.Value = {'Simulation runs!',...
                                         '... please wait ...'};
            tmpRPM      =  app.sim.ac_state.rpm(1);
            tmpTHRUST   = app.sim.ac_state.thrust(1);
            drawnow;
            % Setting demo mode
            if app.modeDemo == true
                
            app.sim.flightpath(1,2:4)      = [app.xPosmEditField.Value,...
                                              app.yPosmEditField.Value,...
                                              app.zPosmEditField.Value];
            app.sim.flightpath(1,11:12)    = [-app.HDGdegEditField.Value+90,...
                                              45];
            x_hdg = cosd(-app.HDGdegEditField.Value+90);
            y_hdg = sind(-app.HDGdegEditField.Value+90);
            z_hdg = sind(app.AOCdegEditField.Value);
            v_length = sqrt(x_hdg^2+y_hdg^2+z_hdg^2);

            app.x_hdg = x_hdg/v_length;
            app.y_hdg = y_hdg/v_length;
            app.z_hdg = z_hdg/v_length;

            app.sim.flightpath(1,6:9)      = [app.x_hdg,...
                                              app.y_hdg,...
                                              app.z_hdg,...
                                              app.AOCdegEditField.Value];
            app.sim.ac_state.rpm(1)        = ...
            app.sim.ac_state.rpm(1) * app.PWREditField.Value / 100;
            app.sim.ac_state.thrust(1)        = ...
            app.sim.ac_state.thrust(1) * app.PWREditField.Value / 100;

            start = 1;
            ending = 1;

            % flight data - running simulation for one time step
            [text, app.imi, simEnd] = ...
                    evalc('Calc(app.numWorkers,start,ending,app.sim)');
            app.InfoboxTextArea.Value = text;

            % Setting back to default values for engine setup -------------
            app.sim.ac_state.rpm(1)      = tmpRPM;
            app.sim.ac_state.thrust(1)   = tmpTHRUST;
            
            % -- finding z-lim for plotting -------------------------------
            if (app.zPosmEditField.Value+50 > max(max(app.sim.geo.elevation)))
                z_lim = ceil(app.zPosmEditField.Value/1000) * 1000;
            else
                z_lim = ceil(max(max(app.sim.geo.elevation)/1000)) * 1000;
            end
            
            if simEnd == true
                planaRefSize = app.sim.geo.size*0.1;
                vertRefSize = (z_lim - min(min(app.sim.geo.elevation))-50)*0.1;

                app_showResults(app.resultPlot,app.imi);
                app.resultPlot.View = [315 45];
                app.resultPlot.ZLim = [min(min(app.sim.geo.elevation))-50,...
                                        z_lim];
                app.resultPlot.XLim = [0 app.sim.geo.size];
                app.resultPlot.YLim = [0 app.sim.geo.size];
                app.cb = colorbar(app.resultPlot);
                colormap(app.resultPlot, 'jet');
                app.cb.Label.String = 'OASPL [dB]';
                hold(app.resultPlot, 'on');
                quiver3(app.resultPlot, app.xPosmEditField.Value,...
                                app.yPosmEditField.Value,...
                                app.zPosmEditField.Value,...
                                app.x_hdg*planaRefSize,...
                                app.y_hdg*planaRefSize,...
                                app.z_hdg*vertRefSize,...
                                'Color', 'red', ...
                                'LineWidth', 5,...
                                'MaxHeadSize', 5); 
                hold(app.resultPlot, 'off');
            end

            else
                return
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Detect and delete parallel pool
            poolobj = gcp('nocreate');
            if ~isempty(poolobj)
                delete(poolobj);
            end

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Terrain')
            xlabel(app.UIAxes, 'x-Axis [m]')
            ylabel(app.UIAxes, 'y-Axis [m]')
            zlabel(app.UIAxes, 'Z')
            app.resultPlot = uiaxes(app.UIFigure);
            title(app.resultPlot, 'ARTS - Noise immission')
            xlabel(app.resultPlot, 'x-Axis [m]')
            ylabel(app.resultPlot, 'y-Axis [m]')
            zlabel(app.resultPlot, 'Elevation_{AMSL} [m]')
            app.StartSimButton = uibutton(app.UIFigure, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = 'Start Sim';
            app.xPosmEditFieldLabel = uilabel(app.UIFigure);
            app.xPosmEditFieldLabel.Text = 'x-Pos [m]';
            app.xPosmEditField = uieditfield(app.UIFigure, 'numeric');
            app.yPosmEditFieldLabel = uilabel(app.UIFigure);
            app.yPosmEditFieldLabel.Text = 'y-Pos [m]';
            app.yPosmEditField = uieditfield(app.UIFigure, 'numeric');
            app.zPosmEditFieldLabel = uilabel(app.UIFigure);
            app.zPosmEditFieldLabel.Text = 'z-Pos [m]';
            app.zPosmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HDGdegEditFieldLabel = uilabel(app.UIFigure);
            app.HDGdegEditFieldLabel.Text = 'HDG [deg]';
            app.HDGdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.HDGdegEditField.Limits = [0 360];
            app.SelectUpdatepositionButton = uibutton(app.UIFigure, 'push');
            app.SelectUpdatepositionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUpdatepositionButtonPushed, true);
            app.SelectUpdatepositionButton.Text = 'Select/Update position';
            app.InfoboxTextAreaLabel = uilabel(app.UIFigure);
            app.InfoboxTextAreaLabel.Text = 'Infobox';
            app.InfoboxTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'arts.png');
            app.AOCdegEditFieldLabel = uilabel(app.UIFigure);
            app.AOCdegEditFieldLabel.Text = 'AOC [deg]';
            app.AOCdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.AOCdegEditField.Limits = [-90 90];
            app.PWREditFieldLabel = uilabel(app.UIFigure);
            app.PWREditFieldLabel.Text = 'PWR [%]';
            app.PWREditField = uieditfield(app.UIFigure, 'numeric');
            app.PWREditField.Limits = [0 100];
            app.PWREditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARTS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end